classdef splApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        buttonLoadWav              matlab.ui.control.Button
        CalibrationEditFieldLabel  matlab.ui.control.Label
        editCalibration            matlab.ui.control.EditField
        sliderMin                  matlab.ui.control.Slider
        sliderMax                  matlab.ui.control.Slider
        tableResult                matlab.ui.control.Table
        editMin                    matlab.ui.control.NumericEditField
        editMax                    matlab.ui.control.NumericEditField
        fileName                   matlab.ui.control.Label
        ChannelDropDownLabel       matlab.ui.control.Label
        editChannel                matlab.ui.control.DropDown
        FsEditFieldLabel           matlab.ui.control.Label
        editSampleRate             matlab.ui.control.NumericEditField
        panelSplSettings           matlab.ui.container.Panel
        labelSplTime               matlab.ui.control.Label
        dropSplTime                matlab.ui.control.DropDown
        labelBandWidth             matlab.ui.control.Label
        dropBandWidth              matlab.ui.control.DropDown
        labelFilterOrder           matlab.ui.control.Label
        editFilterOrder            matlab.ui.control.NumericEditField
        buttonSplUpdate            matlab.ui.control.Button
        checkApplyFilterSpl        matlab.ui.control.CheckBox
        labelTimeHP_3              matlab.ui.control.Label
        editTimeHpSpl              matlab.ui.control.NumericEditField
        labelTimeLP_3              matlab.ui.control.Label
        editTimeLpSpl              matlab.ui.control.NumericEditField
        TabSettings                matlab.ui.container.TabGroup
        tabPlotTime                matlab.ui.container.Tab
        labelTimeHP                matlab.ui.control.Label
        editTimeHp                 matlab.ui.control.NumericEditField
        labelTimeLP                matlab.ui.control.Label
        editTimeLp                 matlab.ui.control.NumericEditField
        buttonTimePlot             matlab.ui.control.Button
        checkApplyFilter           matlab.ui.control.CheckBox
        tabSpectrogram             matlab.ui.container.Tab
        buttonSpecUpdate           matlab.ui.control.Button
        labelSpecFreqMin           matlab.ui.control.Label
        editSpecFreqMin            matlab.ui.control.NumericEditField
        editSpecFreqMax            matlab.ui.control.NumericEditField
        labelSpecFreqMax           matlab.ui.control.Label
        labelSpecWindow            matlab.ui.control.Label
        editSpecWindow             matlab.ui.control.NumericEditField
        labelSpecOverlap           matlab.ui.control.Label
        editSpecOverlap            matlab.ui.control.NumericEditField
        labelSpecNfft              matlab.ui.control.Label
        editSpecNfft               matlab.ui.control.NumericEditField
        checkSpecFreq              matlab.ui.control.CheckBox
        tabAudio                   matlab.ui.container.Tab
        buttonPlaySelection        matlab.ui.control.Button
        buttonStop                 matlab.ui.control.Button
        labelTimeHP_2              matlab.ui.control.Label
        editTimeHpAudio            matlab.ui.control.NumericEditField
        labelTimeLP_2              matlab.ui.control.Label
        editTimeLpAudio            matlab.ui.control.NumericEditField
        checkApplyFilterAudio      matlab.ui.control.CheckBox
        buttonPlay                 matlab.ui.control.Button
        TabTables                  matlab.ui.container.TabGroup
        tabResultOctave            matlab.ui.container.Tab
        tableResultOctave          matlab.ui.control.Table
        tabResultTime              matlab.ui.container.Tab
        tableResultTime            matlab.ui.control.Table
        TabSpl                     matlab.ui.container.TabGroup
        tabSplOctave               matlab.ui.container.Tab
        plotSplOctave              matlab.ui.control.UIAxes
        tabSplTime                 matlab.ui.container.Tab
        plotSplTime                matlab.ui.control.UIAxes
        plotSignal                 matlab.ui.control.UIAxes
        menuSplPlotOctave          matlab.ui.container.ContextMenu
        menuOctavePlotStyle        matlab.ui.container.Menu
        menuOctaveLine             matlab.ui.container.Menu
        menuOctaveBar              matlab.ui.container.Menu
        menuOctaveShow             matlab.ui.container.Menu
        menuOctaveLin              matlab.ui.container.Menu
        menuOctaveAw               matlab.ui.container.Menu
        menuOctaveUndock           matlab.ui.container.Menu
        menuPlotSplTime            matlab.ui.container.ContextMenu
        menuSplTimeShow            matlab.ui.container.Menu
        menuSplTimeLin             matlab.ui.container.Menu
        menuSplTimeAw              matlab.ui.container.Menu
        menuSplTimeCw              matlab.ui.container.Menu
        menuSplTimeUndock          matlab.ui.container.Menu
        menuPlotTime               matlab.ui.container.ContextMenu
        menuAudio                  matlab.ui.container.Menu
        menuAudioPlaySelection     matlab.ui.container.Menu
        menuAudioPlay              matlab.ui.container.Menu
        menuAudioStop              matlab.ui.container.Menu
        menuRestoreview            matlab.ui.container.Menu
        menuTimeUndock             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        audioObject % Description
    end
    
    methods (Access = private)
        
        function plotTime(app, y, t)
            if nargin<3                
                [y,t] = app.getSignal([],'filter',true);
            end
           
            % Plot
            ax = app.plotSignal;
            colorbar(ax,'off')
            set(ax,'YScale','lin')
            yticks(ax,'auto')
            ylim(ax,'auto')
            plot(ax,t,y)
            xlim(ax,[min(t), max(t)])
            ylabel(ax,' ')
            xlabel(ax,'Time (s)')
            
            % Reset patches
            app.patchPlot('xmin',app.editMin.Value,'xmax',app.editMax.Value);   
        end
        
        function patchPlot(app, varargin)
            ax = app.plotSignal;
            %YScale = ax.YScale;
            
            h = findobj(ax);
            xdata = get(h(end),'xdata');
            
            yl = get(ax,'ylim');
            y1 = yl(1);
            y2 = yl(2);
            xleft1 = min(xdata);
            xleft2 = nan;
            xright1 = nan;
            xright2 = max(xdata);
            for n = 1:2:length(varargin)
                switch varargin{n}
                    case 'xmin'
                        xleft2 = varargin{n+1};
                    case 'xmax'
                        xright1 = varargin{n+1};
                    case 'ymin'
                        y1 = varargin{n+1};
                    case 'ymax'
                        y2 = varargin{n+1};
                end
            end
            
            pathcolor = 'k';%[0.5 0.5 0.5];
            patchalpha = 0.3;
                 
            % Patch from left    
            if ~isnan(xleft2)
                % Find and delete existing pathces
                h = findobj(ax, 'Type', 'Patch', 'DisplayName','left');
                delete(h)
            end
            if xleft2>xleft1
                x1 = xleft1;
                x2 = xleft2;
                p = patch(ax, 'XData', [x1 x2 x2 x1], 'YData', [y1 y1 y2 y2], ...
                    'FaceColor', pathcolor, 'FaceAlpha', patchalpha, 'Edgecolor', 'none');
                p.DisplayName = 'left';
            end
            
            % Patch from right
            if ~isnan(xright1)
                 % Find and delete existing pathces
                h = findobj(ax, 'Type', 'Patch', 'DisplayName','right');
                delete(h)
            end
            if xright1<xright2 
                x2 = xright2;
                x1 = xright1;
                p = patch(ax, 'XData', [x1 x2 x2 x1], 'YData', [y1 y1 y2 y2], ...
                    'FaceColor', pathcolor, 'FaceAlpha', patchalpha, 'Edgecolor', 'none');
                p.DisplayName = 'right';
            end
           
        end
        
        function plotOctave(app, type)
            % [Fc,Loct,LAoct]
            values = app.tableResultOctave.Data;
            Fc = values(:,1);
            Loct = values(:,2:3);
            bandwidth = app.dropBandWidth.Value;
  
            switch type
                case 'line'
                    plot(app.plotSplOctave,Loct)
                    
                case 'bar'
                    bar(app.plotSplOctave,Loct)
            end
            legend(app.plotSplOctave,'dB','dB(A)')
            title(app.plotSplOctave, bandwidth);
            xlabel(app.plotSplOctave, 'Frequency (Hz)')
            
            % Fix with xlabels
            if strcmpi(bandwidth,'1/3 octave')
                xt = 2:3:length(Fc);
                xtl = cell(1,length(xt));
                for k = 1:length(xt)
                    n = xt(k);
                    if Fc(n)<1000
                        xtl{k} = num2str(round(Fc(n)));
                    else
                        xtl{k} = sprintf('%.0fk',Fc(n)*1e-3);
                    end
                end
            else
                xt = 1:1:length(Fc);
                xtl = cell(1,length(xt));
                for k = 1:length(xt)
                    n = xt(k);
                    if Fc(n)<1000
                        xtl{k} = num2str(round(Fc(n)));
                    else
                        xtl{k} = sprintf('%.0fk',Fc(n)*1e-3);
                    end
                end
            end
            set(app.plotSplOctave,'xtick',xt,'xticklabel',xtl)
        end
        
        function plotSpl(app)
            values = app.tableResultTime.Data;
            t = values(:,1);
            Lp = values(:,2);
            LA = values(:,3);
            LC = values(:,4);
            plot(app.plotSplTime, t, Lp, t, LA, t, LC)
            lgd = legend(app.plotSplTime, 'dB', 'dB(A)', 'dB(C)');
            xlabel(app.plotSplTime, 'Time (s)')
            title(app.plotSplTime, 'Level vs Time');
            set(app.plotSplTime, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
            
            
            % Set the legend's 'PickableParts' property to 'visible'
            %set(lgd, 'PickableParts', 'visible', 'units', 'pixels');
            
            % Assign a ButtonDownFcn to the legend
            %set(lgd, 'ButtonDownFcn', {@toggleVisibility, app.plotSplTime});
            
            % Callback function to toggle line visibility
            function toggleVisibility(src, ~, ax)
                
                % Get the current point clicked in the legend
                fig = app.UIFigure; %get(ax,'Parent');
                %cp = get(ax,'CurrentPoint')
                cp = get(fig, 'CurrentPoint');
                
                % Determine the corresponding legend entry
                num_entries = length(src.String);
                position = get(src, 'Position');
                y_lower = position(2);
                y_extent = position(4);
                dy = y_extent / num_entries;
                entry_index = num_entries - floor((cp(1,2)-y_lower) / dy);
                
                % Toggle the visibility of the corresponding line
                h = findobj(get(src, 'Parent'), 'DisplayName', src.String{entry_index});
                if ~isempty(h)
                    if strcmp(h.Visible, 'on')
                        h.Visible = 'off';
                    else
                        h.Visible = 'on';
                    end
                end
            end
            
        end
        
        function updatePlot(app, varargin)
            if isempty(varargin)
                value = 'Time';
            else
                value = validatestring(varargin{1}, {'Time', 'Spectrogram'});
            end
            switch value
                case 'Time'
                    app.plotTime()
                case 'Spectrogram'
                    app.plotSpectrogram('reset')
            end
            %h = findobj(app.tabPlotTime)
            %h = [findobj(app.tabPlotTime,'tag','timeSettings');...
            %    findobj(app.tabSpectrogram,'tag','specSettings')];
            %set(h,'enable','on')
        end
        
        function plotSpectrogram(app, varargin)
            % Get guidata
            data = guidata(app.plotSignal);
                        
            y = app.getSignal(data, 'filter', false);
            fs = data.fs;
            
            window = app.editSpecWindow.Value;
            noverlap = app.editSpecOverlap.Value;
            nfft = app.editSpecNfft.Value;
            freqRange = [app.editSpecFreqMin.Value, app.editSpecFreqMax.Value];
            
            % Spectrogram
            if app.checkSpecFreq.Value && (freqRange(1)>1 && freqRange(2)<fs/2)
                F = linspace(freqRange(1), freqRange(2), nfft);
                [~,F,T,P] = spectrogram(y, window, noverlap, F, fs);
            else
                [~,F,T,P] = spectrogram(y, window, noverlap, nfft, fs); 
            end
                    
            % Plot
            ax = app.plotSignal;
            surf(ax, T, F, 10 * log10(P), 'EdgeColor', 'none');
            
            axis(ax, 'tight');
            view(ax, 0, 90); % Set the view angle to 2D
            xlabel(ax, 'Time (s)');
            ylabel(ax, 'Frequency (Hz)');
            %colormap(ax, 'jet'); % Choose the colormap, e.g., 'jet', 'parula', 'bone', etc.
            set(ax,'YScale', 'log', 'ylim',[F(2), fs/2],'ytick',[1,10,100,1000,10000]) %freqRange
            
            % Reset patches
            app.patchPlot('xmin',app.editMin.Value, 'xmax', app.editMax.Value, 'ymin', F(2));   
       
            pos = ax.Position;
            colorbar(ax);
            ax.Position = pos;
            
            
        end
        
        function updateAudioPlayer(app, varargin)
            
            % Get current postion of audio playback in secods
            dt = app.audioObject.CurrentSample/app.audioObject.SampleRate;
            % Get position of playback related time vector
            t = app.editMin.Value+dt;
            
            vLine = findobj(app.plotSignal,'Type','Line','Tag','playbackLine');
            % Check if line exist and move line to correct position
            if isvalid(vLine)
                vLine.XData = t.*[1 1];
            end
            
        end
        
        function [y,varargout] = getSignal(app, data, varargin)      
            if nargin<2 || isempty(data)
                data = guidata(app.buttonSplUpdate);
            end
            
            do_calibrate = true;
            do_filter = false; %app.checkTimeFilter.Value;
            do_selection = false;
            Fc = [app.editTimeHp.Value, app.editTimeLp.Value];
            
            if nargin>2
                for n = 1:2:length(varargin)
                    switch validatestring(varargin{n},{'calibrate', 'selection', 'filter', 'fc'})
                        case 'calibrate'
                            do_calibrate = varargin{n+1};
                        case 'filter'
                            do_filter = varargin{n+1};
                        case 'selection'
                            do_selection = varargin{n+1};
                        case 'fc'
                            Fc = varargin{n+1};
                            %if isempty(Fc)
                            %    do_filter = false;
                            %else
                            %    do_filter = true;
                            %end
                    end
                end
            end
            
            if do_selection
                % Get selection
                timelimits = [app.editMin.Value, app.editMax.Value];
                timelimits(1) = max(timelimits(1),data.time(1));
                timelimits(2) = min(timelimits(2),data.time(end));
                i = data.time>=timelimits(1) & data.time<=timelimits(2);
            else
                i = true(size(data.time));
            end
            
            if nargout>1
                varargout = {data.time(i), data.fs};
            end
            
            % Get input values
            channel = str2double(app.editChannel.Value);
            calib = eval(app.editCalibration.Value);
            if do_calibrate
                y = calib.*data.data(i,channel);
            else
                y = data.data(i,channel);
            end
            
            if do_filter
                Fnyq = data.fs/2;
                Fn = Fc./Fnyq;
                if Fn(1)>0 && Fn(2)<1
                    [B,A] = butter(3, Fn, 'bandpass');
                    y = filtfilt(B,A,y);
                elseif Fn(1)>0
                    [B,A] = butter(3,Fn(1),'high');
                    y = filtfilt(B,A,y);
                elseif Fn(2)<1
                    [B,A] = butter(3,Fn(2),'low');
                    y = filtfilt(B,A,y);
                end                
            end
        end
        
        function audioPlay(app, y, fs, t1)
            % Normalize signal
            yn = (y - mean(y)) ./ max(abs(y - mean(y)));
            % Delete audioObject if exist
            if ~isempty(app.audioObject)
                stop(app.audioObject)
                delete(app.audioObject)
            end
            % Draw playback line
            playbackLine = findobj(app.plotSignal,'type','Line', 'tag', 'playbackLine');
            if ~isempty(playbackLine)
                delete(playbackLine)
            end
            if nargin<4
                t1 = 0;
            end
            playbackLine = line(app.plotSignal, t1.*[1 1], app.plotSignal.YLim, ...
                'Color','k', 'tag', 'playbackLine');
            
            % Create audioobject
            Nh = round(.1*fs);
            h = hann(2*Nh);
            w = .95.*[h(1:Nh);ones(length(yn)-2*Nh,1);h(Nh+1:end)];
            app.audioObject = audioplayer(w.*yn, fs);
            app.audioObject.TimerPeriod = .05;
            app.audioObject.TimerFcn = createCallbackFcn(app, @updateAudioPlayer, true);
            %a.StartFcn = {@app.updateAudioPlayer, l, 'start'};
            %a.StopFcn = {@app.updateAudioPlayer, l, 'stop'};
            
            app.audioObject.play() %blocking
            
            %app.audioStop();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: buttonLoadWav
        function loadData(app, event)
            % Load data
            [file,path] = uigetfile('*.wav');
            if file==0
                return
            end
            filename = fullfile(path,file);
            [data,fs] = audioread(filename);
            t = (0:length(data)-1)./fs;
            
            % Update filename and samplerate labels
            app.fileName.Text = file;
            app.editSampleRate.Value = fs;
            
            % Update slider settings
            app.editChannel.Items = cellstr(num2str((1:size(data,2))'));
            app.editChannel.Value = num2str(size(data,2));
            app.editMin.Value = round(t(1),1);
            app.editMin.Limits = [floor(t(1)),floor(t(end))];
            app.editMax.Value = round(t(end),1);
            app.editMax.Limits = [ceil(t(1)),ceil(t(end))];
            app.sliderMin.Value = 0;
            app.sliderMax.Value = 100;
            
            % Update time plot settings
            app.editTimeLp.Value = fs/2;
            app.editTimeLp.Limits = [1, fs/2];
            app.editTimeHp.Value = 0;
            app.editTimeHp.Limits = [0, fs/2-1];
            set(findobj(app.tabPlotTime, '-not', 'type', 'uitab'),'enable','on');
            
            % Update spectrogram settings
            N = round(.25*fs);
            app.editSpecWindow.Limits = [2,length(data)];
            app.editSpecWindow.Value = N + mod(N, 2);
            
            app.editSpecOverlap.Limits = [0,app.editSpecWindow.Value];
            app.editSpecOverlap.Value = .5*app.editSpecWindow.Value; 
            
            app.editSpecNfft.Limits = [2,10*app.editSpecWindow.Value];
            app.editSpecNfft.Value = min(2*1024, app.editSpecWindow.Value);
            
            app.editSpecFreqMin.Limits = [0,fs/2];
            app.editSpecFreqMin.Value = 0;
            app.editSpecFreqMax.Limits = [0,fs/2];
            app.editSpecFreqMax.Value = fs/2;
            
            set(findobj(app.tabSpectrogram, '-not', 'type', 'uitab'),'enable','on');
            if app.checkSpecFreq.Value
                app.editSpecFreqMin.Enable = 'on';
                app.editSpecFreqMax.Enable = 'on';
            else
                app.editSpecFreqMin.Enable = 'off';
                app.editSpecFreqMax.Enable = 'off';
            end
            
            %Update Audio settings
            app.editTimeLpAudio.Value = fs/2;
            app.editTimeLpAudio.Limits = [1,fs/2];
            app.editTimeHpAudio.Value = 0;
            app.editTimeHpAudio.Limits = [0,fs/2-1];
            
            set(findobj(app.tabAudio, '-not', 'type', 'uitab'),'enable','on');
            if app.checkApplyFilterAudio.Value
                app.editTimeLpAudio.Enable = 'on';
                app.editTimeHpAudio.Enable = 'on';
            else
                app.editTimeLpAudio.Enable = 'off';
                app.editTimeHpAudio.Enable = 'off';
            end
                        
            % Save data to gui
            guidata(app.plotSignal,struct('data',data,'fs',fs,'time',t));
            app.updatePlot('time')
            
            % Enable sound pressure level measurements
            app.panelSplSettings.Enable = 'on';
            
            % Update Spl settings
            app.editTimeLpSpl.Value = fs/2;
            app.editTimeLpSpl.Limits = [1,fs/2];
            app.editTimeHpSpl.Value = 0;
            app.editTimeHpSpl.Limits = [0,fs/2-1];
            if app.checkApplyFilterSpl.Value
                app.editTimeLpSpl.Enable = 'on';
                app.editTimeHpSpl.Enable = 'on';
            else
                app.editTimeLpSpl.Enable = 'off';
                app.editTimeHpSpl.Enable = 'off';
            end
            
            
        end

        % Value changed function: sliderMin
        function updateSliderMin(app, event)
            value = app.sliderMin.Value;
            if value >= app.sliderMax.Value
                app.sliderMin.Value = app.sliderMax.Value - 1;
            end
            t = guidata(app.sliderMin).time;
            tValue = max(t(end)/100*app.sliderMin.Value,t(1));
            app.editMin.Value = round(tValue,1);
            app.patchPlot('xmin', tValue);
            
        end

        % Value changed function: sliderMax
        function updateSliderMax(app, event)
            value = app.sliderMax.Value;
            if value <= app.sliderMin.Value
                app.sliderMax.Value = app.sliderMin.Value + 1;
            end
            t = guidata(app.sliderMax).time;
            tValue = min(t(end)/100*app.sliderMax.Value,t(end));
            app.editMax.Value = round(tValue,1);
            app.patchPlot('xmax', tValue);
        end

        % Button pushed function: buttonSplUpdate
        function calculateSpl(app, event)
            data = guidata(app.buttonSplUpdate);
            
            splSetup = struct('timefilter', app.dropSplTime.Value,...                
                'outputformat', 'struct');
            
            do_filter = app.checkApplyFilterSpl.Value;
            if do_filter
                Fc = [app.editTimeHpSpl.Value, app.editTimeLpSpl.Value];
            else
                Fc = [];
            end
            
            [y,t,fs] = app.getSignal(data, 'selection', true, 'filter', do_filter, 'fc', Fc);
            result = spl(y, t, fs, splSetup);
            
            % Octave analysis
            splSetup.bandwidth = app.dropBandWidth.Value;
            splSetup.filterorder = app.editFilterOrder.Value;
            octresult = spl(y, t, fs, splSetup);
            
            % Display results
            app.tableResult.Data = [...
                [result.Leq;    octresult.Leq],...
                [result.LAeq;   octresult.LAeq],...
                [result.LCeq;   octresult.LCeq], ...
                [result.LAmax;  octresult.LAmax],...
                [result.LCpeak; octresult.LCpeak]];
            app.tableResult.ColumnFormat = {'bank', 'bank', 'bank', 'bank', 'bank'};
            
            app.tableResultTime.Data = [result.t, result.L, result.LA, result.LC];
            
            app.tableResultOctave.Data = [octresult.Fc, octresult.Loct, octresult.LAoct];
           
            app.plotSpl();
            app.plotOctave('line')
            %app.updatePlotOctave()
            
        end

        % Value changed function: editMin
        function updateEditMin(app, event)
            value = app.editMin.Value;
            if value >= app.editMax.Value
                value = app.editMax.Value - 1;
                app.editMin.Value = value;
            end
            t = guidata(app.editMin).time;
            app.sliderMin.Value = value/(t(end)-t(1))*100;
            app.patchPlot('xmin', value);
        end

        % Value changed function: editMax
        function updateEditMax(app, event)
            value = app.editMax.Value;
            if value <= app.editMin.Value
                value = app.editMin.Value + 1;
                app.editMax.Value = value;
            end
            t = guidata(app.editMax).time;
            app.sliderMax.Value = value/(t(end)-t(1))*100;
            app.patchPlot('xmax', value);
        end

        % Value changed function: editChannel
        function updateEditChannel(app, event)
            app.sliderMin.Value = 0;
            app.sliderMax.Value = 100;
            
            t = guidata(app.sliderMin).time;
            
            t1 = t(1);
            app.editMin.Value = round(t1,1);
            
            t2 = t(end);
            app.editMax.Value = round(t2,1);
            
            app.updatePlot()
            %app.patchPlot('xmin',t1, 'xmax', t2);
        end

        % Button pushed function: buttonTimePlot
        function updateTimePlot(app, event)
            % Get guidata
            data = guidata(app.buttonTimePlot);            
            
            % Apply calib and filter
            do_filter = app.checkApplyFilter.Value;
            Fc = [app.editTimeHp.Value, app.editTimeLp.Value];
            
            y = app.getSignal(data, 'filter', do_filter, 'fc', Fc);
            
            app.plotTime(y, data.time)
        end

        % Value changed function: editCalibration
        function updateEditCalibration(app, event)
            value = eval(app.editCalibration.Value);
            if isfinite(value)
                app.updatePlot();
            else
                app.editCalibration.Value = 'nan';
            end
        end

        % Menu selected function: menuOctaveAw, menuOctaveLin
        function updatePlotOctave(app, event)
            src = event.Source;
            h = findobj(app.plotSplOctave, '-property', 'DisplayName', 'DisplayName', src.Text); %'-not', 'type', 'axes'
            if length(h)>1
                h = h(1);
            end
            if ~isempty(h)
                switch lower(h.Visible)
                    case 'off'
                        set(h,'visible','on')
                        src.Checked = true;
                    otherwise
                        set(h,'visible', 'off')
                        src.Checked = false;
                end
            end
        end

        % Button pushed function: buttonSpecUpdate
        function updateSpectrogram(app, event)
            app.plotSpectrogram()
        end

        % Value changed function: editSpecFreqMax
        function updateSpecFreqMax(app, event)
            value = app.editSpecFreqMax.Value;
            if value<=app.editSpecFreqMin.Value
                app.editSpecFreqMin.Value = value-1;
                %app.editTimeHp.Value = value-1;
            end
             %app.editTimeLp.Value = value;
        end

        % Value changed function: editSpecFreqMin
        function updateSpecFreqMin(app, event)
            value = app.editSpecFreqMin.Value;
            if value>=app.editSpecFreqMax.Value
                app.editSpecFreqMax.Value = value+1;
                %app.editTimeLp.Value = value+1;
            end
            %app.editTimeHp.Value = value;
        end

        % Callback function
        function updateSpecOverlap(app, event)
            value = app.editSpecOverlap.Value;
            
        end

        % Value changed function: editSpecWindow
        function updateSpecWindow(app, event)
            value = app.editSpecWindow.Value;
            if app.editSpecOverlap>value
                app.editSpecOverlap = value;
            end
            app.editSpecOverlap.Limits = [0,value];
        end

        % Callback function
        function updateSpecNfft(app, event)
            value = app.editSpecNfft.Value;
            
        end

        % Value changed function: editTimeLp
        function updateEditLp(app, event)
            value = app.editTimeLp.Value;
            if value<=app.editTimeHp.Value
                app.editTimeHp.Value = value-1;
                app.editTimeHpAudio.Value = value-1;
                app.editTimeHpSpl.Value = value-1;
                %app.editSpecFreqMin.Value = value-1;
            end
            %app.editSpecFreqMax.Value = value;
            app.editTimeLpAudio.Value = value;
            app.editTimeLpSpl.Value = value;
        end

        % Value changed function: editTimeHp
        function updateEditHp(app, event)
            value = app.editTimeHp.Value;
            if value>=app.editTimeLp.Value
                app.editTimeLp.Value = value+1;
                app.editTimeLpAudio.Value = value+1;
                app.editTimeLpSpl.Value = value+1;
                %app.editSpecFreqMax.Value = value+1;
            end
            %app.editSpecFreqMin.Value = value;
            app.editTimeHpAudio.Value = value;
            app.editTimeHpSpl.Value = value;
        end

        % Button pushed function: buttonPlaySelection
        function audioPlaySelection(app, event)
            data = guidata(app.buttonPlaySelection);
            do_filter = app.checkApplyFilterAudio.Value;
            if do_filter
                Fc = [app.editTimeHpAudio.Value, app.editTimeLpAudio.Value];
            else
                Fc = [];
            end
            [y, t, fs] = app.getSignal(data,'selection',true,'filter',do_filter, 'fc', Fc);
            app.audioPlay(y, fs, t(1))            
        end

        % Callback function: buttonStop, menuAudioStop
        function audioStop(app, event)
            playbackLine = findobj(app.plotSignal,'type','Line', 'tag', 'playbackLine');
            try
                stop(app.audioObject)
                delete(playbackLine)
            end
        end

        % Menu selected function: menuOctaveBar
        function updatePlotOctaveBar(app, event)
            h1 = findobj(app.plotSplOctave,'-not','type','axes');
            v1 = {};
            if ~isempty(h1)
                v1 = get(findobj(app.plotSplOctave,'-not','type','axes'),'visible');
            end
            app.plotOctave('bar')
            h2 = findobj(app.plotSplOctave,'type','Bar');
            for k = 1:min(length(v1),length(h2))
                set(h2(k),'visible',v1{k})
            end
        end

        % Menu selected function: menuOctaveLine
        function updatePlotOctaveLine(app, event)
            h1 = findobj(app.plotSplOctave,'-not','type','axes');
            v1 = {};
            if ~isempty(h1)
                v1 = get(findobj(app.plotSplOctave,'-not','type','axes'),'visible');
            end
            app.plotOctave('line')
            h2 = findobj(app.plotSplOctave,'type','Line');
            for k = 1:min(length(v1),length(h2))
                set(h2(k),'visible',v1{k})
            end
        end

        % Menu selected function: menuOctaveUndock, 
        % menuSplTimeUndock, menuTimeUndock
        function plotUndock(app, event)
            
            % Get the handle of the plot object in your App Designer GUI
            axesHandle = event.Source.Parent.Parent.CurrentAxes;
            allLegendHandles = findobj(app.UIFigure,'type','legend');
            legendHandle = legend(axesHandle);
            if ~any(legendHandle==allLegendHandles)
                delete(legendHandle)
                legendHandle = [];
            end
            
            % Copy the plot to a new figure window
            fig = figure('Name', 'SPL App Undocked Plot');
            newHandles = copyobj([axesHandle, legendHandle], fig);
            newAxesHandle = newHandles(1);
                        
            %newAxesHandle = findobj(fig,'type','axes');
            newAxesHandle.Units = 'normalized';
            newAxesHandle.OuterPosition = [0 0 1 1];
            newAxesHandle.InnerPosition = [0.1300 0.1100 0.7750 0.8150];
            
            % Remove the plot from the App Designer GUI
            %delete(axesHandle);
            
        end

        % Menu selected function: menuSplTimeAw, menuSplTimeCw, 
        % menuSplTimeLin
        function updatePlotSplTime(app, event)
            src = event.Source;
            h = findobj(app.plotSplTime, '-property', 'DisplayName', 'DisplayName', src.Text); %'-not', 'type', 'axes'
            if length(h)>1
                h = h(1);
            end
            if ~isempty(h)
                switch lower(h.Visible)
                    case 'off'
                        set(h,'visible','on')
                        src.Checked = true;
                    otherwise
                        set(h,'visible', 'off')
                        src.Checked = false;
                end
            end
        end

        % Menu selected function: menuRestoreview
        function updateSliderRestore(app, event)
            app.sliderMax.Value = 100;
            app.sliderMin.Value = 0;
            
            t = guidata(app.sliderMax).time;
            
            app.editMin.Value = round(t(1),1);
            app.editMax.Value = round(t(end),1);
            
            app.patchPlot('xmin',t(1),'xmax', t(end));
            
            app.plotSignal.XLim = [t(1),t(end)];
            app.plotSignal.XTickMode = 'auto';
            app.plotSignal.YLimMode = 'auto';
            app.plotSignal.YTickMode = 'auto';
        end

        % Button pushed function: buttonPlay
        function audioPlayAll(app, event)
            data = guidata(app.buttonPlay);
            do_filter = app.checkApplyFilterAudio.Value;
            if do_filter
                Fc = [app.editTimeHpAudio.Value, app.editTimeLpAudio.Value];
            else
                Fc = [];
            end
            y = app.getSignal(data,'selection',true,'filter',do_filter, 'fc', Fc);
            
            app.audioPlay(y, data.fs)
        end

        % Menu selected function: menuAudioPlaySelection
        function audioPlaySelectionMenu(app, event)
            data = guidata(app.menuAudioPlaySelection);
            [y, t, fs] = app.getSignal(data,'selection',true,'filter',true);
            app.audioPlay(y, fs, t(1))  
        end

        % Menu selected function: menuAudioPlay
        function audioPlayAllMenu(app, event)
            data = guidata(app.buttonPlaySelection);
            y = app.getSignal(data,'selection',false,'filter',true);
            app.audioPlay(y, data.fs)
        end

        % Value changed function: checkApplyFilterAudio
        function updateFilterAudio(app, event)
            value = app.checkApplyFilterAudio.Value;
            if value
                app.editTimeLpAudio.Enable = 'on';
                app.editTimeHpAudio.Enable = 'on';
            else
                app.editTimeLpAudio.Enable = 'off';
                app.editTimeHpAudio.Enable = 'off';
            end
        end

        % Value changed function: checkApplyFilterSpl
        function updateFilterOctave(app, event)
            value = app.checkApplyFilterSpl.Value;
            if value
                app.editTimeLpSpl.Enable = 'on';
                app.editTimeHpSpl.Enable = 'on';
            else
                app.editTimeLpSpl.Enable = 'off';
                app.editTimeHpSpl.Enable = 'off';
            end
        end

        % Value changed function: checkSpecFreq
        function updateSpecFreq(app, event)
            value = app.checkSpecFreq.Value;
            if value
                app.editSpecFreqMin.Enable = 'on';
                app.editSpecFreqMax.Enable = 'on';
            else
                app.editSpecFreqMin.Enable = 'off';
                app.editSpecFreqMax.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.buttonLoadWav = uibutton(app.UIFigure, 'push');
            app.buttonLoadWav.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.buttonLoadWav.Text = 'Load WAV file';
            app.CalibrationEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.editCalibration = uieditfield(app.UIFigure, 'text');
            app.editCalibration.ValueChangedFcn = createCallbackFcn(app, @updateEditCalibration, true);
            app.editCalibration.Value = '20.97*23.6';
            app.sliderMin = uislider(app.UIFigure);
            app.sliderMin.MajorTicks = [];
            app.sliderMin.MajorTickLabels = {''};
            app.sliderMin.ValueChangedFcn = createCallbackFcn(app, @updateSliderMin, true);
            app.sliderMin.MinorTicks = [];
            app.sliderMax = uislider(app.UIFigure);
            app.sliderMax.MajorTicks = [];
            app.sliderMax.MajorTickLabels = {''};
            app.sliderMax.ValueChangedFcn = createCallbackFcn(app, @updateSliderMax, true);
            app.sliderMax.MinorTicks = [];
            app.sliderMax.Value = 100;
            app.tableResult = uitable(app.UIFigure);
            app.tableResult.ColumnName = {'Leq'; 'LAeq'; 'LCeq'; 'LAmax'; 'LCpeak'};
            app.tableResult.RowName = {'full'; 'sum'};
            app.editMin = uieditfield(app.UIFigure, 'numeric');
            app.editMin.ValueDisplayFormat = '%.1f';
            app.editMin.ValueChangedFcn = createCallbackFcn(app, @updateEditMin, true);
            app.editMax = uieditfield(app.UIFigure, 'numeric');
            app.editMax.ValueDisplayFormat = '%.1f';
            app.editMax.ValueChangedFcn = createCallbackFcn(app, @updateEditMax, true);
            app.editMax.Value = 100;
            app.fileName = uilabel(app.UIFigure);
            app.fileName.Text = 'No file loaded';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.editChannel = uidropdown(app.UIFigure);
            app.editChannel.Items = {'1', '2', '3', '4'};
            app.editChannel.ValueChangedFcn = createCallbackFcn(app, @updateEditChannel, true);
            app.editChannel.Value = '1';
            app.FsEditFieldLabel = uilabel(app.UIFigure);
            app.FsEditFieldLabel.Text = 'Fs';
            app.editSampleRate = uieditfield(app.UIFigure, 'numeric');
            app.editSampleRate.Limits = [0 Inf];
            app.editSampleRate.ValueDisplayFormat = '%.0f';
            app.editSampleRate.Enable = 'off';
            app.panelSplSettings = uipanel(app.UIFigure);
            app.panelSplSettings.Enable = 'off';
            app.panelSplSettings.Title = 'Spl settings';
            app.labelSplTime = uilabel(app.panelSplSettings);
            app.labelSplTime.Text = 'Time';
            app.dropSplTime = uidropdown(app.panelSplSettings);
            app.dropSplTime.Items = {'Fast', 'Slow'};
            app.dropSplTime.Value = 'Fast';
            app.labelBandWidth = uilabel(app.panelSplSettings);
            app.labelBandWidth.Text = 'Bandwidth';
            app.dropBandWidth = uidropdown(app.panelSplSettings);
            app.dropBandWidth.Items = {'1/3 octave', '1 octave'};
            app.dropBandWidth.Value = '1/3 octave';
            app.labelFilterOrder = uilabel(app.panelSplSettings);
            app.labelFilterOrder.Text = 'Filter order';
            app.editFilterOrder = uieditfield(app.panelSplSettings, 'numeric');
            app.editFilterOrder.Limits = [0 10];
            app.editFilterOrder.ValueDisplayFormat = '%.0f';
            app.editFilterOrder.Value = 6;
            app.buttonSplUpdate = uibutton(app.panelSplSettings, 'push');
            app.buttonSplUpdate.ButtonPushedFcn = createCallbackFcn(app, @calculateSpl, true);
            app.buttonSplUpdate.Tag = 'specSettings';
            app.buttonSplUpdate.Text = 'Calculate SPL';
            app.checkApplyFilterSpl = uicheckbox(app.panelSplSettings);
            app.checkApplyFilterSpl.ValueChangedFcn = createCallbackFcn(app, @updateFilterOctave, true);
            app.checkApplyFilterSpl.Text = 'Apply filter to SPL calc.';
            app.labelTimeHP_3 = uilabel(app.panelSplSettings);
            app.labelTimeHP_3.Tag = 'timeSettings';
            app.labelTimeHP_3.Enable = 'off';
            app.labelTimeHP_3.Text = 'High pass';
            app.editTimeHpSpl = uieditfield(app.panelSplSettings, 'numeric');
            app.editTimeHpSpl.Tag = 'timeSettings';
            app.editTimeHpSpl.Enable = 'off';
            app.labelTimeLP_3 = uilabel(app.panelSplSettings);
            app.labelTimeLP_3.Tag = 'timeSettings';
            app.labelTimeLP_3.Enable = 'off';
            app.labelTimeLP_3.Text = 'Low pass';
            app.editTimeLpSpl = uieditfield(app.panelSplSettings, 'numeric');
            app.editTimeLpSpl.Tag = 'timeSettings';
            app.editTimeLpSpl.Enable = 'off';
            app.TabSettings = uitabgroup(app.UIFigure);
            app.tabPlotTime = uitab(app.TabSettings);
            app.tabPlotTime.Title = 'Time';
            app.labelTimeHP = uilabel(app.tabPlotTime);
            app.labelTimeHP.Tag = 'timeSettings';
            app.labelTimeHP.Enable = 'off';
            app.labelTimeHP.Text = 'High pass';
            app.editTimeHp = uieditfield(app.tabPlotTime, 'numeric');
            app.editTimeHp.ValueChangedFcn = createCallbackFcn(app, @updateEditHp, true);
            app.editTimeHp.Tag = 'timeSettings';
            app.editTimeHp.Enable = 'off';
            app.labelTimeLP = uilabel(app.tabPlotTime);
            app.labelTimeLP.Tag = 'timeSettings';
            app.labelTimeLP.Enable = 'off';
            app.labelTimeLP.Text = 'Low pass';
            app.editTimeLp = uieditfield(app.tabPlotTime, 'numeric');
            app.editTimeLp.ValueChangedFcn = createCallbackFcn(app, @updateEditLp, true);
            app.editTimeLp.Tag = 'timeSettings';
            app.editTimeLp.Enable = 'off';
            app.buttonTimePlot = uibutton(app.tabPlotTime, 'push');
            app.buttonTimePlot.ButtonPushedFcn = createCallbackFcn(app, @updateTimePlot, true);
            app.buttonTimePlot.Tag = 'timeSettings';
            app.buttonTimePlot.Enable = 'off';
            app.buttonTimePlot.Text = 'Plot time signal';
            app.checkApplyFilter = uicheckbox(app.tabPlotTime);
            app.checkApplyFilter.Enable = 'off';
            app.checkApplyFilter.Text = 'Filter time signal';
            app.tabSpectrogram = uitab(app.TabSettings);
            app.tabSpectrogram.Title = 'Spec';
            app.buttonSpecUpdate = uibutton(app.tabSpectrogram, 'push');
            app.buttonSpecUpdate.ButtonPushedFcn = createCallbackFcn(app, @updateSpectrogram, true);
            app.buttonSpecUpdate.Tag = 'specSettings';
            app.buttonSpecUpdate.Enable = 'off';
            app.buttonSpecUpdate.Text = 'Plot spectrogram';
            app.labelSpecFreqMin = uilabel(app.tabSpectrogram);
            app.labelSpecFreqMin.Tag = 'specSettings';
            app.labelSpecFreqMin.Enable = 'off';
            app.labelSpecFreqMin.Text = 'lower';
            app.editSpecFreqMin = uieditfield(app.tabSpectrogram, 'numeric');
            app.editSpecFreqMin.Limits = [0 Inf];
            app.editSpecFreqMin.ValueChangedFcn = createCallbackFcn(app, @updateSpecFreqMin, true);
            app.editSpecFreqMin.Tag = 'specSettings';
            app.editSpecFreqMin.Enable = 'off';
            app.editSpecFreqMax = uieditfield(app.tabSpectrogram, 'numeric');
            app.editSpecFreqMax.ValueChangedFcn = createCallbackFcn(app, @updateSpecFreqMax, true);
            app.editSpecFreqMax.Tag = 'specSettings';
            app.editSpecFreqMax.Enable = 'off';
            app.labelSpecFreqMax = uilabel(app.tabSpectrogram);
            app.labelSpecFreqMax.Tag = 'specSettings';
            app.labelSpecFreqMax.Enable = 'off';
            app.labelSpecFreqMax.Text = 'upper';
            app.labelSpecWindow = uilabel(app.tabSpectrogram);
            app.labelSpecWindow.Tag = 'specSettings';
            app.labelSpecWindow.Enable = 'off';
            app.labelSpecWindow.Text = 'Window';
            app.editSpecWindow = uieditfield(app.tabSpectrogram, 'numeric');
            app.editSpecWindow.Limits = [0 48000];
            app.editSpecWindow.RoundFractionalValues = 'on';
            app.editSpecWindow.ValueDisplayFormat = '%.0f';
            app.editSpecWindow.ValueChangedFcn = createCallbackFcn(app, @updateSpecWindow, true);
            app.editSpecWindow.Tag = 'specSettings';
            app.editSpecWindow.Enable = 'off';
            app.labelSpecOverlap = uilabel(app.tabSpectrogram);
            app.labelSpecOverlap.Tag = 'specSettings';
            app.labelSpecOverlap.Enable = 'off';
            app.labelSpecOverlap.Text = 'Noverlap';
            app.editSpecOverlap = uieditfield(app.tabSpectrogram, 'numeric');
            app.editSpecOverlap.Limits = [0 48000];
            app.editSpecOverlap.RoundFractionalValues = 'on';
            app.editSpecOverlap.ValueDisplayFormat = '%.0f';
            app.editSpecOverlap.Tag = 'specSettings';
            app.editSpecOverlap.Enable = 'off';
            app.labelSpecNfft = uilabel(app.tabSpectrogram);
            app.labelSpecNfft.Tag = 'specSettings';
            app.labelSpecNfft.Enable = 'off';
            app.labelSpecNfft.Text = 'nfft';
            app.editSpecNfft = uieditfield(app.tabSpectrogram, 'numeric');
            app.editSpecNfft.Tag = 'specSettings';
            app.editSpecNfft.Enable = 'off';
            app.checkSpecFreq = uicheckbox(app.tabSpectrogram);
            app.checkSpecFreq.ValueChangedFcn = createCallbackFcn(app, @updateSpecFreq, true);
            app.checkSpecFreq.Enable = 'off';
            app.checkSpecFreq.Text = 'Specify freqeuncy range';
            app.tabAudio = uitab(app.TabSettings);
            app.tabAudio.Title = 'Audio';
            app.buttonPlaySelection = uibutton(app.tabAudio, 'push');
            app.buttonPlaySelection.ButtonPushedFcn = createCallbackFcn(app, @audioPlaySelection, true);
            app.buttonPlaySelection.Enable = 'off';
            app.buttonPlaySelection.Text = 'Play selection';
            app.buttonStop = uibutton(app.tabAudio, 'push');
            app.buttonStop.ButtonPushedFcn = createCallbackFcn(app, @audioStop, true);
            app.buttonStop.Enable = 'off';
            app.buttonStop.Text = 'Stop';
            app.labelTimeHP_2 = uilabel(app.tabAudio);
            app.labelTimeHP_2.Tag = 'timeSettings';
            app.labelTimeHP_2.Enable = 'off';
            app.labelTimeHP_2.Text = 'High pass';
            app.editTimeHpAudio = uieditfield(app.tabAudio, 'numeric');
            app.editTimeHpAudio.Tag = 'timeSettings';
            app.editTimeHpAudio.Enable = 'off';
            app.labelTimeLP_2 = uilabel(app.tabAudio);
            app.labelTimeLP_2.Tag = 'timeSettings';
            app.labelTimeLP_2.Enable = 'off';
            app.labelTimeLP_2.Text = 'Low pass';
            app.editTimeLpAudio = uieditfield(app.tabAudio, 'numeric');
            app.editTimeLpAudio.Tag = 'timeSettings';
            app.editTimeLpAudio.Enable = 'off';
            app.checkApplyFilterAudio = uicheckbox(app.tabAudio);
            app.checkApplyFilterAudio.ValueChangedFcn = createCallbackFcn(app, @updateFilterAudio, true);
            app.checkApplyFilterAudio.Enable = 'off';
            app.checkApplyFilterAudio.Text = 'Apply filter to audio';
            app.buttonPlay = uibutton(app.tabAudio, 'push');
            app.buttonPlay.ButtonPushedFcn = createCallbackFcn(app, @audioPlayAll, true);
            app.buttonPlay.Enable = 'off';
            app.buttonPlay.Text = 'Play';
            app.TabTables = uitabgroup(app.UIFigure);
            app.tabResultOctave = uitab(app.TabTables);
            app.tabResultOctave.Title = 'Octave';
            app.tableResultOctave = uitable(app.tabResultOctave);
            app.tableResultOctave.ColumnName = {'Freq'; 'dB'; 'dB(A)'};
            app.tableResultOctave.RowName = {};
            app.tabResultTime = uitab(app.TabTables);
            app.tabResultTime.Title = 'Time';
            app.tableResultTime = uitable(app.tabResultTime);
            app.tableResultTime.ColumnName = {'Time'; 'dB'; 'dBA'; 'dBC'};
            app.tableResultTime.RowName = {};
            app.TabSpl = uitabgroup(app.UIFigure);
            app.tabSplOctave = uitab(app.TabSpl);
            app.tabSplOctave.Title = 'Octave';
            app.plotSplOctave = uiaxes(app.tabSplOctave);
            title(app.plotSplOctave, '1/3 ovtave')
            xlabel(app.plotSplOctave, 'Freq')
            ylabel(app.plotSplOctave, 'Level')
            zlabel(app.plotSplOctave, 'Z')
            app.tabSplTime = uitab(app.TabSpl);
            app.tabSplTime.Title = 'Time';
            app.plotSplTime = uiaxes(app.tabSplTime);
            title(app.plotSplTime, 'Level vs Time  ')
            xlabel(app.plotSplTime, 'Time (s)')
            ylabel(app.plotSplTime, 'Level')
            app.plotSignal = uiaxes(app.UIFigure);
            xlabel(app.plotSignal, 'Time')
            ylabel(app.plotSignal, 'Y-axis')
            app.menuSplPlotOctave = uicontextmenu(app.UIFigure);
            % Assign app.menuSplPlotOctave
            app.plotSplOctave.ContextMenu = app.menuSplPlotOctave;
            app.menuOctavePlotStyle = uimenu(app.menuSplPlotOctave);
            app.menuOctavePlotStyle.Text = 'Style';
            app.menuOctaveLine = uimenu(app.menuOctavePlotStyle);
            app.menuOctaveLine.MenuSelectedFcn = createCallbackFcn(app, @updatePlotOctaveLine, true);
            app.menuOctaveLine.Text = 'Line';
            app.menuOctaveBar = uimenu(app.menuOctavePlotStyle);
            app.menuOctaveBar.MenuSelectedFcn = createCallbackFcn(app, @updatePlotOctaveBar, true);
            app.menuOctaveBar.Text = 'Bar';
            app.menuOctaveShow = uimenu(app.menuSplPlotOctave);
            app.menuOctaveShow.Text = 'Show';
            app.menuOctaveLin = uimenu(app.menuOctaveShow);
            app.menuOctaveLin.MenuSelectedFcn = createCallbackFcn(app, @updatePlotOctave, true);
            app.menuOctaveLin.Checked = 'on';
            app.menuOctaveLin.Text = 'dB';
            app.menuOctaveAw = uimenu(app.menuOctaveShow);
            app.menuOctaveAw.MenuSelectedFcn = createCallbackFcn(app, @updatePlotOctave, true);
            app.menuOctaveAw.Checked = 'on';
            app.menuOctaveAw.Text = 'dB(A)';
            app.menuOctaveUndock = uimenu(app.menuSplPlotOctave);
            app.menuOctaveUndock.MenuSelectedFcn = createCallbackFcn(app, @plotUndock, true);
            app.menuOctaveUndock.Separator = 'on';
            app.menuOctaveUndock.Text = 'Undock';
            app.menuPlotSplTime = uicontextmenu(app.UIFigure);
            % Assign app.menuPlotSplTime
            app.plotSplTime.ContextMenu = app.menuPlotSplTime;
            app.menuSplTimeShow = uimenu(app.menuPlotSplTime);
            app.menuSplTimeShow.Text = 'Show';
            app.menuSplTimeLin = uimenu(app.menuSplTimeShow);
            app.menuSplTimeLin.MenuSelectedFcn = createCallbackFcn(app, @updatePlotSplTime, true);
            app.menuSplTimeLin.Checked = 'on';
            app.menuSplTimeLin.Text = 'dB';
            app.menuSplTimeAw = uimenu(app.menuSplTimeShow);
            app.menuSplTimeAw.MenuSelectedFcn = createCallbackFcn(app, @updatePlotSplTime, true);
            app.menuSplTimeAw.Checked = 'on';
            app.menuSplTimeAw.Text = 'dB(A)';
            app.menuSplTimeCw = uimenu(app.menuSplTimeShow);
            app.menuSplTimeCw.MenuSelectedFcn = createCallbackFcn(app, @updatePlotSplTime, true);
            app.menuSplTimeCw.Checked = 'on';
            app.menuSplTimeCw.Text = 'dB(C)';
            app.menuSplTimeUndock = uimenu(app.menuPlotSplTime);
            app.menuSplTimeUndock.MenuSelectedFcn = createCallbackFcn(app, @plotUndock, true);
            app.menuSplTimeUndock.Separator = 'on';
            app.menuSplTimeUndock.Text = 'Undock';
            app.menuPlotTime = uicontextmenu(app.UIFigure);
            % Assign app.menuPlotTime
            app.plotSignal.ContextMenu = app.menuPlotTime;
            app.menuAudio = uimenu(app.menuPlotTime);
            app.menuAudio.Separator = 'on';
            app.menuAudio.Text = 'Audio';
            app.menuAudioPlaySelection = uimenu(app.menuAudio);
            app.menuAudioPlaySelection.MenuSelectedFcn = createCallbackFcn(app, @audioPlaySelectionMenu, true);
            app.menuAudioPlaySelection.Text = 'Play selection';
            app.menuAudioPlay = uimenu(app.menuAudio);
            app.menuAudioPlay.MenuSelectedFcn = createCallbackFcn(app, @audioPlayAllMenu, true);
            app.menuAudioPlay.Text = 'Play';
            app.menuAudioStop = uimenu(app.menuAudio);
            app.menuAudioStop.MenuSelectedFcn = createCallbackFcn(app, @audioStop, true);
            app.menuAudioStop.Text = 'Stop';
            app.menuRestoreview = uimenu(app.menuPlotTime);
            app.menuRestoreview.MenuSelectedFcn = createCallbackFcn(app, @updateSliderRestore, true);
            app.menuRestoreview.Separator = 'on';
            app.menuRestoreview.Text = 'Restore view';
            app.menuTimeUndock = uimenu(app.menuPlotTime);
            app.menuTimeUndock.MenuSelectedFcn = createCallbackFcn(app, @plotUndock, true);
            app.menuTimeUndock.Separator = 'on';
            app.menuTimeUndock.Text = 'Undock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end